classdef gauss < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GaussianmixturemodeldesignerUIFigure  matlab.ui.Figure
        FileMenu              matlab.ui.container.Menu
        OpenmodelMenu         matlab.ui.container.Menu
        SaveMenu              matlab.ui.container.Menu
        SaveandexitMenu       matlab.ui.container.Menu
        ExitMenu              matlab.ui.container.Menu
        HelpMenu              matlab.ui.container.Menu
        AboutMenu             matlab.ui.container.Menu
        Toolbar               matlab.ui.container.Toolbar
        PushTool              matlab.ui.container.toolbar.PushTool
        PushTool2             matlab.ui.container.toolbar.PushTool
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        GridLayout8           matlab.ui.container.GridLayout
        GridLayout6           matlab.ui.container.GridLayout
        GridLayout10          matlab.ui.container.GridLayout
        AddGaussianButton     matlab.ui.control.Button
        GridLayout9           matlab.ui.container.GridLayout
        RemoveGaussianButton  matlab.ui.control.Button
        ListBox               matlab.ui.control.ListBox
        ListBoxLabel          matlab.ui.control.Label
        RightPanel            matlab.ui.container.Panel
        RightPanel2           matlab.ui.container.Panel
        GridLayout2           matlab.ui.container.GridLayout
        GridLayout3           matlab.ui.container.GridLayout
        alphaLabel            matlab.ui.control.Label
        GridLayout7           matlab.ui.container.GridLayout
        SaveButton            matlab.ui.control.Button
        ResetButton           matlab.ui.control.Button
        sigmaLabel            matlab.ui.control.Label
        GridLayout5           matlab.ui.container.GridLayout
        EditField_4           matlab.ui.control.NumericEditField
        EditField_4Label      matlab.ui.control.Label
        EditField_2           matlab.ui.control.NumericEditField
        EditField_2Label      matlab.ui.control.Label
        EditField_3           matlab.ui.control.NumericEditField
        EditField_3Label      matlab.ui.control.Label
        EditField             matlab.ui.control.NumericEditField
        Label                 matlab.ui.control.Label
        GridLayout4           matlab.ui.container.GridLayout
        EditField_5           matlab.ui.control.NumericEditField
        EditField_5Label      matlab.ui.control.Label
        EditField_6           matlab.ui.control.NumericEditField
        EditField_6Label      matlab.ui.control.Label
        Slider                matlab.ui.control.Slider
        SliderLabel           matlab.ui.control.Label
        muxyLabel             matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mu          double
        Sigma       double
        alpha       double
        gauss_no    double
        model_no    int8
        file        string
        closed      string
    end
    
    methods (Access = private)
        
        function update_list(app)
            lb_items=[];
            for k=1:length(app.alpha)
                lb_items=[lb_items strcat(string(k),". (",... 
                    string(app.Mu(1,k)),", ",...
                    string(app.Mu(2,k)),")")];
            end
            app.ListBox.Items=lb_items;
        end
        
        function save_model(app)
            if isempty(app.file)
               [file,path]=uiputfile(strcat("gauss_",...
                   string(round(posixtime(datetime))),".mat"));
            else
               [file,path]=uiputfile(app.file);
            end
            if isequal(file,0)||isequal(path,0)
                disp('file has not been saved')
            else
                Mu=app.Mu;
                Sigma=app.Sigma;
                alpha=app.alpha;
                app.file=fullfile(path,file);
                save(fullfile(path,file),"Mu","Sigma","alpha");
                disp(['model file has been saved to ',file])
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            file_list=dir("gauss_*.mat");
            
            try 
                file_list=file_list(end);
                load(fullfile(file_list.folder,file_list.name),"Sigma",...
                    "Mu","alpha");
                app.Mu=Mu;
                app.Sigma=Sigma;
                app.alpha=alpha;
                app.file=fullfile(file_list.folder,file_list.name);

                msgbox(strcat("Found model in ",file_list.name),...
                    "Found model","help");
                disp(['model ' file_list.name ' has been loaded'])
            catch
                
                app.Mu(:,1)=[.33333;.14285];
                app.Mu(:,2)=[.83332;.1    ];
                app.Mu(:,3)=[.4    ;.57142];
                app.Mu(:,4)=[.83332;.85713];
                app.Sigma(:,:,1)=[.1;.1]*[.1;.1]'*1e-3+eye(2)*1e-3;
                app.Sigma(:,:,2)=app.Sigma(:,:,1);
                app.Sigma(:,:,3)=app.Sigma(:,:,1);
                app.Sigma(:,:,4)=app.Sigma(:,:,1);
                app.alpha=[.25;.25;.25;.25];
            end

            update_list(app);
        end

        % Close request function: GaussianmixturemodeldesignerUIFigure
        function GaussianmixturemodeldesignerUIFigureCloseRequest(app, event)
            if app.PushTool2.Enable=="on"
                answer=questdlg('Would you like to save the model?',...
	                'Save',...
	                'Save','Cancel','Save');
                % Handle response
                switch answer
                case 'Save'
                    save_model(app);
                end
                
            end
            app.closed="yes";
            % delete(app)
            % delete is in eneraware.m
        end

        % Clicked callback: ListBox
        function ListBoxClicked(app, event)
            item=event.InteractionInformation.Item;
            
            if isempty(item)
                return
            end
            app.gauss_no=item;

            mu_x=app.EditField_6;
            mu_x.Enable="on";
            mu_y=app.EditField_5;
            mu_y.Enable="on";
            sigma_1=app.EditField;
            sigma_1.Enable="on";
            sigma_2=app.EditField_3;
            sigma_2.Enable="on";
            sigma_3=app.EditField_2;
            sigma_3.Enable="on";
            sigma_4=app.EditField_4;
            sigma_4.Enable="on";
            alpha=app.Slider;
            alpha.Enable="on";

            app.ResetButton.Enable="on";
            app.SaveButton.Enable="on";

            mu_x.Value=app.Mu(1,item);
            mu_y.Value=app.Mu(2,item);
            sigma_1.Value=app.Sigma(1,1,item);
            sigma_2.Value=app.Sigma(2,1,item);
            sigma_3.Value=app.Sigma(1,2,item);
            sigma_4.Value=app.Sigma(2,2,item);
            alpha.Value=app.alpha(item);
            
            if length(app.alpha)>1
                app.RemoveGaussianButton.Enable="on";
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            mu_x=app.EditField_6;
            mu_y=app.EditField_5;
            sigma_1=app.EditField;
            sigma_2=app.EditField_3;
            sigma_3=app.EditField_2;
            sigma_4=app.EditField_4;
            alpha=app.Slider;

            mu_x.Value=app.Mu(1,app.gauss_no);
            mu_y.Value=app.Mu(2,app.gauss_no);
            sigma_1.Value=app.Sigma(1,1,app.gauss_no);
            sigma_2.Value=app.Sigma(2,1,app.gauss_no);
            sigma_3.Value=app.Sigma(1,2,app.gauss_no);
            sigma_4.Value=app.Sigma(2,2,app.gauss_no);
            alpha.Value=app.alpha(app.gauss_no);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            mu_x=app.EditField_6;
            mu_y=app.EditField_5;
            sigma_1=app.EditField;
            sigma_2=app.EditField_3;
            sigma_3=app.EditField_2;
            sigma_4=app.EditField_4;
            alpha=app.Slider;

            alpha_=app.alpha;
            alpha_(app.gauss_no)=alpha.Value;
            if sum(alpha_)>1
                msgbox(strcat("Sum of alphas must be 1"),...
                    "Error","error");
                return;
            end
            if or(or(mu_x.Value>1,mu_x.Value<0),...
                  or(mu_y.Value>1,mu_y.Value<0))
                msgbox(strcat("All coordinates must be normalized"),...
                    "Error","error");
                return;
            end

            app.Mu(1,app.gauss_no)=mu_x.Value;
            app.Mu(2,app.gauss_no)=mu_y.Value;
            app.Sigma(1,1,app.gauss_no)=sigma_1.Value;
            app.Sigma(2,1,app.gauss_no)=sigma_2.Value;
            app.Sigma(1,2,app.gauss_no)=sigma_3.Value;
            app.Sigma(2,2,app.gauss_no)=sigma_4.Value;
            app.alpha(app.gauss_no)=alpha.Value;

            update_list(app);
            app.PushTool2.Enable="on";
            app.SaveMenu.Enable="on";
            app.SaveandexitMenu.Enable="on";
        end

        % Button pushed function: AddGaussianButton
        function AddGaussianButtonPushed(app, event)
            app.Mu=cat(2,app.Mu,[.0;.0]);
            app.Sigma=cat(3,app.Sigma,[.1;.1]*[.1;.1]'*1e-3+eye(2)*1e-3);
            app.alpha=[app.alpha;.0];

            update_list(app);

            app.PushTool2.Enable="on";
            app.SaveMenu.Enable="on";
            app.SaveandexitMenu.Enable="on";
        end

        % Button pushed function: RemoveGaussianButton
        function RemoveGaussianButtonPushed(app, event)
            if ~isempty(app.gauss_no)
                app.Sigma(:,:,app.gauss_no)=[];
                app.Mu(:,app.gauss_no)=[];
                app.alpha(app.gauss_no)=[];

                update_list(app);
                app.PushTool2.Enable="on";
                app.SaveMenu.Enable="on";
                app.SaveandexitMenu.Enable="on";
            end
            app.RemoveGaussianButton.Enable="off"
        end

        % Callback function: PushTool2
        function PushTool2Clicked(app, event)

            save_model(app);
            app.PushTool2.Enable="off";
            app.SaveMenu.Enable="off";
            app.SaveandexitMenu.Enable="off";
        end

        % Callback function: PushTool
        function PushToolClicked(app, event)
            [file,path]=uigetfile("*.mat");
            if isequal(file,0)||isequal(path,0)
                disp('file has not been opened')
            else
                load(fullfile(path,file),"Sigma","Mu","alpha");
                app.Mu=Mu;
                app.Sigma=Sigma;
                app.alpha=alpha;

                disp(['model ' file ' has been loaded'])
                update_list(app);
                app.PushTool2.Enable="off";
                app.SaveMenu.Enable="off";
                app.SaveandexitMenu.Enable="off";
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            msgbox(sprintf(strcat("Gaussian mixture model designer",...
                "\n\n",...
                "Version:\t  1.0","\n",...
                "Author:\t   Adam Seewald, Yale University","\n",...
                "License:\t  CC BY-NC-SA 4.0")),...
                "Gaussian mixture model designer");
        end

        % Menu selected function: OpenmodelMenu
        function OpenmodelMenuSelected(app, event)
            PushToolClicked(app,event);
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            PushTool2Clicked(app,event);
        end

        % Menu selected function: SaveandexitMenu
        function SaveandexitMenuSelected(app, event)
            save_model(app);
            app.closed="yes";
            % delete(app)
            % delete is in eneraware.m
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            GaussianmixturemodeldesignerUIFigureCloseRequest(app,event);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.GaussianmixturemodeldesignerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GaussianmixturemodeldesignerUIFigure = uifigure('Visible', 'off');
            app.GaussianmixturemodeldesignerUIFigure.Name = 'Gaussian mixture model designer';
            app.GaussianmixturemodeldesignerUIFigure.CloseRequestFcn = createCallbackFcn(app, @GaussianmixturemodeldesignerUIFigureCloseRequest, true);
            app.GaussianmixturemodeldesignerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.GaussianmixturemodeldesignerUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenmodelMenu = uimenu(app.FileMenu);
            app.OpenmodelMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenmodelMenuSelected, true);
            app.OpenmodelMenu.Accelerator = 'o';
            app.OpenmodelMenu.Text = 'Open model';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Enable = 'off';
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = 'Save';
            app.SaveandexitMenu = uimenu(app.FileMenu);
            app.SaveandexitMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveandexitMenuSelected, true);
            app.SaveandexitMenu.Enable = 'off';
            app.SaveandexitMenu.Text = 'Save and exit';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.GaussianmixturemodeldesignerUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.Toolbar = uitoolbar(app.GaussianmixturemodeldesignerUIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Open model file'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'open-icon.png');
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'Save model to file'};
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Enable = 'off';
            app.PushTool2.Icon = fullfile(pathToMLAPP, 'save-icon.png');
            app.GridLayout = uigridlayout(app.GaussianmixturemodeldesignerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout8 = uigridlayout(app.LeftPanel);
            app.GridLayout6 = uigridlayout(app.GridLayout8);
            app.ListBoxLabel = uilabel(app.GridLayout6);
            app.ListBoxLabel.Text = '';
            app.ListBox = uilistbox(app.GridLayout6);
            app.ListBox.Items = {};
            app.ListBox.ClickedFcn = createCallbackFcn(app, @ListBoxClicked, true);
            app.ListBox.Value = {};
            app.GridLayout9 = uigridlayout(app.GridLayout6);
            app.RemoveGaussianButton = uibutton(app.GridLayout9, 'push');
            app.RemoveGaussianButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveGaussianButtonPushed, true);
            app.RemoveGaussianButton.Enable = 'off';
            app.RemoveGaussianButton.Text = 'Remove Gaussian';
            app.GridLayout10 = uigridlayout(app.GridLayout6);
            app.AddGaussianButton = uibutton(app.GridLayout10, 'push');
            app.AddGaussianButton.ButtonPushedFcn = createCallbackFcn(app, @AddGaussianButtonPushed, true);
            app.AddGaussianButton.Text = 'Add Gaussian';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel2 = uipanel(app.RightPanel);
            app.GridLayout2 = uigridlayout(app.RightPanel2);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.muxyLabel = uilabel(app.GridLayout3);
            app.muxyLabel.Interpreter = 'latex';
            app.muxyLabel.Text = {''; ''; '\mu\, ($x, y$):'};
            app.SliderLabel = uilabel(app.GridLayout3);
            app.SliderLabel.Interpreter = 'latex';
            app.SliderLabel.Enable = 'off';
            app.SliderLabel.Text = '';
            app.Slider = uislider(app.GridLayout3);
            app.Slider.Limits = [0 1];
            app.Slider.Enable = 'off';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.EditField_6Label = uilabel(app.GridLayout4);
            app.EditField_6Label.Text = '';
            app.EditField_6 = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_6.Enable = 'off';
            app.EditField_5Label = uilabel(app.GridLayout4);
            app.EditField_5Label.Text = '';
            app.EditField_5 = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_5.Enable = 'off';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.Label = uilabel(app.GridLayout5);
            app.Label.Text = '';
            app.EditField = uieditfield(app.GridLayout5, 'numeric');
            app.EditField.Enable = 'off';
            app.EditField_3Label = uilabel(app.GridLayout5);
            app.EditField_3Label.Enable = 'off';
            app.EditField_3Label.Text = '';
            app.EditField_3 = uieditfield(app.GridLayout5, 'numeric');
            app.EditField_3.Enable = 'off';
            app.EditField_2Label = uilabel(app.GridLayout5);
            app.EditField_2Label.Text = '';
            app.EditField_2 = uieditfield(app.GridLayout5, 'numeric');
            app.EditField_2.Enable = 'off';
            app.EditField_4Label = uilabel(app.GridLayout5);
            app.EditField_4Label.Enable = 'off';
            app.EditField_4Label.Text = '';
            app.EditField_4 = uieditfield(app.GridLayout5, 'numeric');
            app.EditField_4.Enable = 'off';
            app.sigmaLabel = uilabel(app.GridLayout3);
            app.sigmaLabel.Interpreter = 'latex';
            app.sigmaLabel.Text = {''; ''; '\sigma:'};
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.ResetButton = uibutton(app.GridLayout7, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            app.SaveButton = uibutton(app.GridLayout7, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.alphaLabel = uilabel(app.GridLayout3);
            app.alphaLabel.Interpreter = 'latex';
            app.alphaLabel.Text = '\alpha:';
            % Show the figure after all components are created
            app.GaussianmixturemodeldesignerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gauss

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GaussianmixturemodeldesignerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GaussianmixturemodeldesignerUIFigure)
        end
    end
end